classdef main_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CellSelect3DMorphUIFigure       matlab.ui.Figure
        ChannelsPanel                   matlab.ui.container.Panel
        ChannelofinterestEditFieldLabel  matlab.ui.control.Label
        ChannelofinterestEditField      matlab.ui.control.NumericEditField
        NumberofchannelsEditFieldLabel  matlab.ui.control.Label
        NumberofchannelsEditField       matlab.ui.control.NumericEditField
        DoneButton                      matlab.ui.control.Button
        PathsPanel                      matlab.ui.container.Panel
        SelectFileButton                matlab.ui.control.Button
        AcceptedformatstiflsmLabel      matlab.ui.control.Label
        FileEditField                   matlab.ui.control.EditField
        OutputfolderButton              matlab.ui.control.Button
        DefaultsameasinptLabel          matlab.ui.control.Label
        OutputEditField                 matlab.ui.control.EditField
        Image                           matlab.ui.control.Image
        ScalePanel                      matlab.ui.container.Panel
        Zscale1frameEditFieldLabel      matlab.ui.control.Label
        Zscale1frameEditField           matlab.ui.control.NumericEditField
        XYscale1pixelLabel              matlab.ui.control.Label
        XYscale1pixelEditField          matlab.ui.control.NumericEditField
        mLabel                          matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        ConfigPanel                     matlab.ui.container.Panel
        ErosionEditFieldLabel           matlab.ui.control.Label
        ErosionEditField                matlab.ui.control.NumericEditField
        HTML                            matlab.ui.control.HTML
        HTML_2                          matlab.ui.control.HTML
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            [FileName,PathName] = uigetfile({'*.tif';'*.lsm'},'Choose an image','MultiSelect','off');
            if all(FileName==0)
                return
            end
            app.FileEditField.Value = fullfile(PathName,FileName);
            app.OutputEditField.Value = PathName;
        end

        % Button pushed function: OutputfolderButton
        function OutputfolderButtonPushed(app, event)
            
            PathName = uigetdir('.','Choose Output Folder');
            if all(PathName==0)
                return
            end
            app.OutputEditField.Value = PathName;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            assignin('base','ch', getappdata(handles.figure1,'ch'));
            assignin('base','scale', getappdata(handles.figure1,'scale'));
            assignin('base','zscale', getappdata(handles.figure1,'zscale'));
            assignin('base','file', getappdata(handles.figure1,'file'));
            assignin('base','pathname', getappdata(handles.figure1,'pathname'));
            assignin('base','pathname', getappdata(handles.figure1,'pathname'));
            assignin('base','ChannelOfInterest', getappdata(handles.figure1,'ChannelOfInterest'));
            assignin('base','Erosion', getappdata(handles.figure1,'Erosion'));    
            app.delete()
        end

        % Close request function: CellSelect3DMorphUIFigure
        function CellSelect3DMorphUIFigureCloseRequest(app, event)
            delete(app)
            assignin('base','file', '');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CellSelect3DMorphUIFigure = uifigure('Visible', 'off');
            app.CellSelect3DMorphUIFigure.Name = 'CellSelect-3DMorph';
            app.CellSelect3DMorphUIFigure.CloseRequestFcn = createCallbackFcn(app, @CellSelect3DMorphUIFigureCloseRequest, true);
            app.ChannelsPanel = uipanel(app.CellSelect3DMorphUIFigure);
            app.ChannelsPanel.Title = 'Channels';
            app.ChannelofinterestEditFieldLabel = uilabel(app.ChannelsPanel);
            app.ChannelofinterestEditFieldLabel.Text = 'Channel of interest';
            app.ChannelofinterestEditField = uieditfield(app.ChannelsPanel, 'numeric');
            app.ChannelofinterestEditField.Limits = [1 Inf];
            app.ChannelofinterestEditField.Value = 1;
            app.NumberofchannelsEditFieldLabel = uilabel(app.ChannelsPanel);
            app.NumberofchannelsEditFieldLabel.Text = 'Number of channels';
            app.NumberofchannelsEditField = uieditfield(app.ChannelsPanel, 'numeric');
            app.NumberofchannelsEditField.Limits = [1 Inf];
            app.NumberofchannelsEditField.Value = 1;
            app.DoneButton = uibutton(app.CellSelect3DMorphUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.PathsPanel = uipanel(app.CellSelect3DMorphUIFigure);
            app.PathsPanel.Title = 'Paths';
            app.SelectFileButton = uibutton(app.PathsPanel, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.AcceptedformatstiflsmLabel = uilabel(app.PathsPanel);
            app.AcceptedformatstiflsmLabel.Text = 'Accepted formats: .tif, .lsm';
            app.FileEditField = uieditfield(app.PathsPanel, 'text');
            app.OutputfolderButton = uibutton(app.PathsPanel, 'push');
            app.OutputfolderButton.ButtonPushedFcn = createCallbackFcn(app, @OutputfolderButtonPushed, true);
            app.OutputfolderButton.Text = 'Output folder';
            app.DefaultsameasinptLabel = uilabel(app.PathsPanel);
            app.DefaultsameasinptLabel.Text = 'Default same as inpt';
            app.OutputEditField = uieditfield(app.PathsPanel, 'text');
            app.Image = uiimage(app.CellSelect3DMorphUIFigure);
            app.Image.ImageSource = 'CGKlab-logo.png';
            app.ScalePanel = uipanel(app.CellSelect3DMorphUIFigure);
            app.ScalePanel.Title = 'Scale';
            app.Zscale1frameEditFieldLabel = uilabel(app.ScalePanel);
            app.Zscale1frameEditFieldLabel.Text = 'Z scale: 1 frame =';
            app.Zscale1frameEditField = uieditfield(app.ScalePanel, 'numeric');
            app.Zscale1frameEditField.Limits = [0 Inf];
            app.XYscale1pixelLabel = uilabel(app.ScalePanel);
            app.XYscale1pixelLabel.Text = 'XY scale: 1 pixel =';
            app.XYscale1pixelEditField = uieditfield(app.ScalePanel, 'numeric');
            app.XYscale1pixelEditField.Limits = [0 Inf];
            app.mLabel = uilabel(app.ScalePanel);
            app.mLabel.Text = 'μm';
            app.mLabel_2 = uilabel(app.ScalePanel);
            app.mLabel_2.Text = 'μm';
            app.ConfigPanel = uipanel(app.CellSelect3DMorphUIFigure);
            app.ConfigPanel.Title = 'Config';
            app.ErosionEditFieldLabel = uilabel(app.ConfigPanel);
            app.ErosionEditFieldLabel.Text = 'Erosion';
            app.ErosionEditField = uieditfield(app.ConfigPanel, 'numeric');
            app.ErosionEditField.Limits = [0 Inf];
            app.ErosionEditField.Value = 3;
            app.HTML = uihtml(app.CellSelect3DMorphUIFigure);
            app.HTML.HTMLSource = '<style> body{font-size: 12px;}</style>For more info and updates visit: <a href="https://github.com/CGK-Laboratory/CellSelect-3DMorph">github.com/CGK-Laboratory/CellSelect-3DMorph</a>';
            app.HTML_2 = uihtml(app.CellSelect3DMorphUIFigure);
            app.HTML_2.HTMLSource = 'CellSelect-3DMorph';
            % Show the figure after all components are created
            app.CellSelect3DMorphUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_window

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CellSelect3DMorphUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CellSelect3DMorphUIFigure)
        end
    end
end